classdef OpenTTD_Heightmap_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenTTDHeightmapGeneratorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SAVEMAPButton                  matlab.ui.control.Button
        PreviewPanel                   matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        HeightmapPanel                 matlab.ui.container.Panel
        UIAxes1                        matlab.ui.control.UIAxes
        ControlPanel                   matlab.ui.container.Panel
        GENERATEMAPButton              matlab.ui.control.Button
        PRESETSDropDown                matlab.ui.control.DropDown
        PRESETSDropDownLabel           matlab.ui.control.Label
        SEALEVELSliderLabel            matlab.ui.control.Label
        MASKMODIFIEREditField          matlab.ui.control.NumericEditField
        MASKMODIFIEREditFieldLabel     matlab.ui.control.Label
        MASKTYPEDropDown               matlab.ui.control.DropDown
        MASKTYPEDropDownLabel          matlab.ui.control.Label
        NOISEITERATIONSEditField       matlab.ui.control.NumericEditField
        NOISEITERATIONSEditFieldLabel  matlab.ui.control.Label
        QFACTOREditField               matlab.ui.control.NumericEditField
        QFACTOREditFieldLabel          matlab.ui.control.Label
        PFACTOREditField               matlab.ui.control.NumericEditField
        PFACTOREditFieldLabel          matlab.ui.control.Label
        DECAYFUNCTIONDropDown          matlab.ui.control.DropDown
        DECAYFUNCTIONDropDownLabel     matlab.ui.control.Label
        WIDTHDropDown                  matlab.ui.control.DropDown
        WIDTHDropDownLabel             matlab.ui.control.Label
        SEALEVELSlider                 matlab.ui.control.Slider
        DEPTHDropDown                  matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        compiled_heightmap double % The compiled heightmap generated by the terrain generation function.
        col_map double = [0.0745	0.6235	1.0000;
            0.8196	0.8392	0.1922;
            0.0588	0.1882	0.0314;
            0.0755	0.2030	0.0487;
            0.0923	0.2177	0.0660;
            0.1090	0.2324	0.0832;
            0.1257	0.2472	0.1005;
            0.1424	0.2619	0.1178;
            0.1591	0.2766	0.1351;
            0.1759	0.2914	0.1524;
            0.1926	0.3061	0.1697;
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            0.5804	0.3294	0.0941]
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PFACTOREditField
        function PFACTOREditFieldValueChanged(app, event)
            p = app.PFACTOREditField.Value;

        end

        % Value changed function: QFACTOREditField
        function QFACTOREditFieldValueChanged(app, event)
            q = app.QFACTOREditField.Value;
            
        end

        % Value changed function: DEPTHDropDown
        function DEPTHDropDownValueChanged(app, event)
            depth = str2double(app.DEPTHDropDown.Value);
            
        end

        % Value changed function: DECAYFUNCTIONDropDown
        function DECAYFUNCTIONDropDownValueChanged(app, event)
            if app.DECAYFUNCTIONDropDown.Value == "Inverse"
                decay_fun = 1;
            elseif app.DECAYFUNCTIONDropDown.Value == "Exponential"
                decay_fun = 2;
            end
            
        end

        % Value changed function: WIDTHDropDown
        function WIDTHDropDownValueChanged(app, event)
            width = str2double(app.WIDTHDropDown.Value);
            
        end

        % Value changed function: NOISEITERATIONSEditField
        function NOISEITERATIONSEditFieldValueChanged(app, event)
            iterations = app.NOISEITERATIONSEditField.Value;
            
        end

        % Value changed function: MASKTYPEDropDown
        function MASKTYPEDropDownValueChanged(app, event)
            if app.MASKTYPEDropDown.Value == "Coastal"
                mask_type = 1;
            elseif app.MASKTYPEDropDown.Value == "Island"
                mask_type = 2;
            else
                mask_type = 0;
            end
            
        end

        % Value changed function: MASKMODIFIEREditField
        function MASKMODIFIEREditFieldValueChanged(app, event)
            mask_modifier = app.MASKMODIFIEREditField.Value;
            
        end

        % Value changed function: SEALEVELSlider
        function SEALEVELSliderValueChanged(app, event)
            sea_level = round(app.SEALEVELSlider.Value);
            
        end

        % Value changed function: PRESETSDropDown
        function PRESETSDropDownValueChanged(app, event)
            preset = app.PRESETSDropDown.Value;
            if preset == "Volcanic Islands"
                app.PFACTOREditField.Value = 1.5;
                app.QFACTOREditField.Value = 1.5;
                app.SEALEVELSlider.Value = 80;
                app.NOISEITERATIONSEditField.Value = 8;
                app.DECAYFUNCTIONDropDown.Value = "Inverse";
                app.MASKTYPEDropDown.Value = "Island";
                app.MASKMODIFIEREditField.Value = 1.5;
            elseif preset == "Sandy Banks"
                app.PFACTOREditField.Value = 0.25;
                app.QFACTOREditField.Value = 1.1;
                app.SEALEVELSlider.Value = 100;
                app.NOISEITERATIONSEditField.Value = 4;
                app.DECAYFUNCTIONDropDown.Value = "Exponential";
                app.MASKTYPEDropDown.Value = "Coastal";
                app.MASKMODIFIEREditField.Value = 1;
            elseif preset == "Mountains and Valleys"
                app.PFACTOREditField.Value = 2.5;
                app.QFACTOREditField.Value = 1.3;
                app.SEALEVELSlider.Value = 0;
                app.NOISEITERATIONSEditField.Value = 8;
                app.DECAYFUNCTIONDropDown.Value = "Inverse";
                app.MASKTYPEDropDown.Value = "None";
                app.MASKMODIFIEREditField.Value = 0;
            elseif preset == "Capitol Peak"
                app.PFACTOREditField.Value = 3;
                app.QFACTOREditField.Value = 1.1;
                app.SEALEVELSlider.Value = 0;
                app.NOISEITERATIONSEditField.Value = 10;
                app.DECAYFUNCTIONDropDown.Value = "Inverse";
                app.MASKTYPEDropDown.Value = "Island";
                app.MASKMODIFIEREditField.Value = 0.75;
            else
            end
        end

        % Button pushed function: GENERATEMAPButton
        function GENERATEMAPButtonPushed(app, event)
            %Make sure all the values are assigned properly
            depth = str2double(app.DEPTHDropDown.Value);
            width = str2double(app.WIDTHDropDown.Value);
            sea_level = round(app.SEALEVELSlider.Value);
            p = double(app.PFACTOREditField.Value);
            q = double(app.QFACTOREditField.Value);
            iterations = double(app.NOISEITERATIONSEditField.Value);
            if app.DECAYFUNCTIONDropDown.Value == "Inverse"
                decay_fun = 1;
            elseif app.DECAYFUNCTIONDropDown.Value == "Exponential"
                decay_fun = 2;
            end
            if app.MASKTYPEDropDown.Value == "Coastal"
                mask_type = 1;
            elseif app.MASKTYPEDropDown.Value == "Island"
                mask_type = 2;
            else
                mask_type = 0;
            end
            mask_modifier = app.MASKMODIFIEREditField.Value;
            %Run height generation function
            
            app.compiled_heightmap = Heightmap_Generator(depth,width,sea_level,p,q,iterations,decay_fun,mask_type,mask_modifier);

            imshow(app.compiled_heightmap,gray,'Parent',app.UIAxes1)
            surf(app.UIAxes2,app.compiled_heightmap);
            colormap(app.UIAxes2,app.col_map)
            shading(app.UIAxes2,"interp")
            if min(depth,width) <=256
                zlim(app.UIAxes2,[0,256])
            else
                zlim(app.UIAxes2,[0,min(depth,width)])
            end
            xlim(app.UIAxes2,[0,depth])
            ylim(app.UIAxes2,[0,width])
            colorbar(app.UIAxes2,"east","Color",[0.15 0.15 0.15])
           

        end

        % Button pushed function: SAVEMAPButton
        function SAVEMAPButtonPushed(app, event)
            if size(app.compiled_heightmap) > 1
                imwrite(app.compiled_heightmap,gray,'custom_heightmap.png')
                msgbox('Heightmap Saved!')
            else
                msgbox('No Heightmap!')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenTTDHeightmapGeneratorUIFigure = uifigure('Visible', 'off');
            app.OpenTTDHeightmapGeneratorUIFigure.Name = 'OpenTTD Heightmap Generator';
            app.OpenTTDHeightmapGeneratorUIFigure.Icon = fullfile(pathToMLAPP, 'landscape.png');
            app.GridLayout = uigridlayout(app.OpenTTDHeightmapGeneratorUIFigure);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = 'DEPTH';
            app.DEPTHDropDown = uidropdown(app.ControlPanel);
            app.DEPTHDropDown.Items = {'64', '128', '256', '512', '1024', '2048', '4096'};
            app.DEPTHDropDown.ItemsData = {'64', '128', '256', '512', '1024', '2048', '4096'};
            app.DEPTHDropDown.ValueChangedFcn = createCallbackFcn(app, @DEPTHDropDownValueChanged, true);
            app.DEPTHDropDown.Tooltip = {'Select the size of the map'};
            app.DEPTHDropDown.Value = '64';
            app.SEALEVELSlider = uislider(app.ControlPanel);
            app.SEALEVELSlider.Limits = [0 255];
            app.SEALEVELSlider.MajorTicks = [0 25 50 75 100 125 150 175 200 225 250];
            app.SEALEVELSlider.ValueChangedFcn = createCallbackFcn(app, @SEALEVELSliderValueChanged, true);
            app.SEALEVELSlider.Tooltip = {'Manually adjust the sea level by lowering the map into the ground'};
            app.WIDTHDropDownLabel = uilabel(app.ControlPanel);
            app.WIDTHDropDownLabel.Text = 'WIDTH';
            app.WIDTHDropDown = uidropdown(app.ControlPanel);
            app.WIDTHDropDown.Items = {'64', '128', '256', '512', '1024', '2048', '4096'};
            app.WIDTHDropDown.ItemsData = {'64', '128', '256', '512', '1024', '2048', '4096'};
            app.WIDTHDropDown.ValueChangedFcn = createCallbackFcn(app, @WIDTHDropDownValueChanged, true);
            app.WIDTHDropDown.Tooltip = {'Select the size of the map'};
            app.WIDTHDropDown.Value = '64';
            app.DECAYFUNCTIONDropDownLabel = uilabel(app.ControlPanel);
            app.DECAYFUNCTIONDropDownLabel.Text = 'DECAY FUNCTION';
            app.DECAYFUNCTIONDropDown = uidropdown(app.ControlPanel);
            app.DECAYFUNCTIONDropDown.Items = {'Inverse', 'Exponential'};
            app.DECAYFUNCTIONDropDown.ValueChangedFcn = createCallbackFcn(app, @DECAYFUNCTIONDropDownValueChanged, true);
            app.DECAYFUNCTIONDropDown.Tooltip = {'How the base noise is "eroded"'};
            app.DECAYFUNCTIONDropDown.Value = 'Inverse';
            app.PFACTOREditFieldLabel = uilabel(app.ControlPanel);
            app.PFACTOREditFieldLabel.Text = 'P-FACTOR';
            app.PFACTOREditField = uieditfield(app.ControlPanel, 'numeric');
            app.PFACTOREditField.ValueChangedFcn = createCallbackFcn(app, @PFACTOREditFieldValueChanged, true);
            app.PFACTOREditField.Tooltip = {'Applies to the decay function. Higher values of P will result in "spikier" landscapes'};
            app.QFACTOREditFieldLabel = uilabel(app.ControlPanel);
            app.QFACTOREditFieldLabel.Text = 'Q-FACTOR';
            app.QFACTOREditField = uieditfield(app.ControlPanel, 'numeric');
            app.QFACTOREditField.ValueChangedFcn = createCallbackFcn(app, @QFACTOREditFieldValueChanged, true);
            app.QFACTOREditField.Tooltip = {'Applies to noise iterations. A higher value of Q will exponentially reduce the effect of each iteration.'};
            app.NOISEITERATIONSEditFieldLabel = uilabel(app.ControlPanel);
            app.NOISEITERATIONSEditFieldLabel.Text = 'NOISE ITERATIONS';
            app.NOISEITERATIONSEditField = uieditfield(app.ControlPanel, 'numeric');
            app.NOISEITERATIONSEditField.Limits = [1 Inf];
            app.NOISEITERATIONSEditField.ValueChangedFcn = createCallbackFcn(app, @NOISEITERATIONSEditFieldValueChanged, true);
            app.NOISEITERATIONSEditField.Tooltip = {'How many noise iterations will affect the height map. More iterations will create more mountain ridges. A value more than 10 creates detail unlikely to show up in the game.'};
            app.NOISEITERATIONSEditField.Value = 1;
            app.MASKTYPEDropDownLabel = uilabel(app.ControlPanel);
            app.MASKTYPEDropDownLabel.Text = 'MASK TYPE';
            app.MASKTYPEDropDown = uidropdown(app.ControlPanel);
            app.MASKTYPEDropDown.Items = {'None', 'Coastal', 'Island'};
            app.MASKTYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @MASKTYPEDropDownValueChanged, true);
            app.MASKTYPEDropDown.Tooltip = {'Select a mask to force coastlines. If left at none, there is a chance there will be no water, especially with low sea levels.'};
            app.MASKTYPEDropDown.Value = 'None';
            app.MASKMODIFIEREditFieldLabel = uilabel(app.ControlPanel);
            app.MASKMODIFIEREditFieldLabel.Text = 'MASK MODIFIER';
            app.MASKMODIFIEREditField = uieditfield(app.ControlPanel, 'numeric');
            app.MASKMODIFIEREditField.ValueChangedFcn = createCallbackFcn(app, @MASKMODIFIEREditFieldValueChanged, true);
            app.MASKMODIFIEREditField.Tooltip = {'Changes the size of the mask. ex: > 1 with mask type "island" will reduce the possible landmass area.'};
            app.SEALEVELSliderLabel = uilabel(app.ControlPanel);
            app.SEALEVELSliderLabel.Text = 'SEA LEVEL';
            app.PRESETSDropDownLabel = uilabel(app.ControlPanel);
            app.PRESETSDropDownLabel.Text = 'PRESETS';
            app.PRESETSDropDown = uidropdown(app.ControlPanel);
            app.PRESETSDropDown.Items = {'None', 'Volcanic Islands', 'Sandy Banks', 'Mountains and Valleys', 'Capitol Peak'};
            app.PRESETSDropDown.ValueChangedFcn = createCallbackFcn(app, @PRESETSDropDownValueChanged, true);
            app.PRESETSDropDown.Tooltip = {'Choose preset values'};
            app.PRESETSDropDown.Value = 'None';
            app.GENERATEMAPButton = uibutton(app.ControlPanel, 'push');
            app.GENERATEMAPButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEMAPButtonPushed, true);
            app.GENERATEMAPButton.Text = 'GENERATE MAP';
            app.HeightmapPanel = uipanel(app.GridLayout);
            app.HeightmapPanel.Title = 'Heightmap';
            app.UIAxes1 = uiaxes(app.HeightmapPanel);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes2 = uiaxes(app.PreviewPanel);
            app.UIAxes2.ZLim = [0 255];
            app.UIAxes2.CLim = [0 255];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.AlphaScale = 'log';
            app.SAVEMAPButton = uibutton(app.GridLayout, 'push');
            app.SAVEMAPButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEMAPButtonPushed, true);
            app.SAVEMAPButton.Text = 'SAVE MAP';
            % Show the figure after all components are created
            app.OpenTTDHeightmapGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenTTD_Heightmap_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenTTDHeightmapGeneratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenTTDHeightmapGeneratorUIFigure)
        end
    end
end